classdef simpleApp < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        QuitButton                      matlab.ui.control.Button
        EditField                       matlab.ui.control.EditField
        DropDown                        matlab.ui.control.DropDown
        HTML_2                          matlab.ui.control.HTML
        orLabel                         matlab.ui.control.Label
        HTML                            matlab.ui.control.HTML
        StartsessionButton              matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        StartbaselineButton             matlab.ui.control.Button
        SetButton                       matlab.ui.control.Button
        OptionvalueLabel                matlab.ui.control.Label
        OptionnameLabel                 matlab.ui.control.Label
        NeuroFeedbackLabsimpleappLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            %fprintf('Channel 1 value %d invert %d\n', comp.Channel1CheckBox.Value, comp.invert_1.Value);
            %fprintf('Channel 2 value %d invert %d\n', comp.Channel2CheckBox.Value, comp.invert_2.Value);
            %fprintf('Channel 3 value %d invert %d\n', comp.Channel3CheckBox.Value, comp.invert_3.Value);           
            %fprintf('Channel 4 value %d invert %d\n', comp.Channel4CheckBox.Value, comp.invert_4.Value);   
            
            comp.DropDown.Items = {      ...
                'warnsrate'
                'fileNameAsr'
                'fileNameOut'
                'fileNameRaw'
                'pauseSecond'
                'baselineSessionDuration'
                'sessionDuration'
                'streamFile'
                'chans'
                'srate'
                'srateHardware'
                'windowSize'
                'windowInc'
                'chanmask'
                'filtFlag'
                'badchanFlag'
                'asrFlag'
                'asrCutoff'
                'icaFlag'
                'averefFlag'
                'chanCorr'
                'badChans'
                'freqrange'
                'freqdb'
                'freqprocess'
                'nfft'
                'feedbackMode'
                'threshold'
                'thresholdMem'
                'thresholdMode'
                'thresholdWin'
                'thresholdPer'
                'dynRange'
                'dynRangeInc'
                'dynRangeDec'
                'maxChange'
                'capdBchange'
                'feedbackfield'
                'diary'
                'funcinit'
                'funcfeedback'
                'funcend'                };
            comp.DropDown.Position = [182 285 100 22];
            comp.DropDown.Value = 'pauseSecond';

            %drawnow(); pause(.05)                       % <--------- add
            %comp.UIFigure.WindowState='maximized';       % <--------- add
            %set(comp, 'position', [1,1,442,515])
        end

        % Button pushed function: SetButton
        function SetButtonPushed(comp, event)

            valNum = str2num(comp.EditField.Value);
            if ~isempty(valNum)
                messagevar.options.(comp.DropDown.Value) = valNum;
            else
                messagevar.options.(comp.DropDown.Value) = comp.EditField.Value;
            end
            messagevar.command = [];
            assignin('base', 'messagevar', messagevar);
            disp('New options applied')
        end

        % Button pushed function: StartbaselineButton
        function StartbaselineButtonPushed(comp, event)
            messagevar.command = 'start';
            messagevar.options.runmode = 'baseline';
            messagevar.options.fileNameAsr = sprintf('data_nfblab_%s_asr_filter.mat',  datestr(now, 'yyyy-mm-dd_HH-MM'));
            comp.StopButton.Enable = 'on';
            comp.StartbaselineButton.Enable = 'off';
            comp.StartsessionButton.Enable = 'off';
            comp.orLabel.Enable = 'off';
            assignin('base', 'messagevar', messagevar);            
        end

        % Button pushed function: StartsessionButton
        function StartsessionButtonPushed(comp, event)
            messagevar.command = 'start';
            messagevar.options.runmode = 'trial';
            comp.StopButton.Enable = 'on';
            comp.StartbaselineButton.Enable = 'off';
            comp.StartsessionButton.Enable = 'off';   
            comp.orLabel.Enable = 'off';
            assignin('base', 'messagevar', messagevar);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(comp, event)
            messagevar.command = 'stop';
            messagevar.options = [];
            comp.StopButton.Enable = 'off';
            comp.StartbaselineButton.Enable = 'on';
            comp.StartsessionButton.Enable = 'on';   
            comp.orLabel.Enable = 'on';
            assignin('base', 'messagevar', messagevar);
        end

        % Button pushed function: QuitButton
        function QuitButtonPushed(comp, event)
            messagevar.command = 'quit';
            messagevar.options = [];
            assignin('base', 'messagevar', messagevar);
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)

        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 562 422];
            comp.BackgroundColor = [0.8627 0.9098 0.9882];
            comp.NeuroFeedbackLabsimpleappLabel = uilabel(comp);
            comp.NeuroFeedbackLabsimpleappLabel.Text = 'NeuroFeedbackLab simple app';
            comp.OptionnameLabel = uilabel(comp);
            comp.OptionnameLabel.Text = 'Option name';
            comp.OptionvalueLabel = uilabel(comp);
            comp.OptionvalueLabel.Text = 'Option value';
            comp.SetButton = uibutton(comp, 'push');
            comp.SetButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SetButtonPushed, true);
            comp.SetButton.Text = 'Set';
            comp.StartbaselineButton = uibutton(comp, 'push');
            comp.StartbaselineButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @StartbaselineButtonPushed, true);
            comp.StartbaselineButton.Text = 'Start baseline';
            comp.StopButton = uibutton(comp, 'push');
            comp.StopButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @StopButtonPushed, true);
            comp.StopButton.Enable = 'off';
            comp.StopButton.Text = 'Stop';
            comp.StartsessionButton = uibutton(comp, 'push');
            comp.StartsessionButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @StartsessionButtonPushed, true);
            comp.StartsessionButton.Text = 'Start session';
            comp.HTML = uihtml(comp);
            comp.HTML.HTMLSource = '<hr>';
            comp.orLabel = uilabel(comp);
            comp.orLabel.Text = 'or';
            comp.HTML_2 = uihtml(comp);
            comp.HTML_2.HTMLSource = '<hr>';
            comp.DropDown = uidropdown(comp);
            comp.DropDown.Items = {'op1', 'op2'};
            comp.DropDown.Value = 'op1';
            comp.EditField = uieditfield(comp, 'text');
            comp.QuitButton = uibutton(comp, 'push');
            comp.QuitButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @QuitButtonPushed, true);
            comp.QuitButton.Text = 'Quit';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end